classdef visualize_state_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        state1Label              matlab.ui.control.Label
        state_1                  matlab.ui.control.Spinner
        estimationCheckBox       matlab.ui.control.CheckBox
        predictionCheckBox       matlab.ui.control.CheckBox
        state2Label              matlab.ui.control.Label
        state_2                  matlab.ui.control.Spinner
        realCheckBox             matlab.ui.control.CheckBox
        ButtonGroup              matlab.ui.container.ButtonGroup
        statevstimeButton        matlab.ui.control.RadioButton
        statevsstateButton       matlab.ui.control.RadioButton
        trajectoryButton         matlab.ui.control.RadioButton
        headinganddipolesButton  matlab.ui.control.RadioButton
        plotto                   matlab.ui.control.UIAxes
        axisequalCheckBox        matlab.ui.control.CheckBox
        PLOTButton               matlab.ui.control.Button
        sSliderLabel             matlab.ui.control.Label
        sSlider                  matlab.ui.control.Slider
        HoldoffButton            matlab.ui.control.Button
        HoldonButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: state_1
        function state_1ValueChanged(app, event)
            value = app.state_1.Value;
            
        end

        % Value changed function: axisequalCheckBox
        function axisequalCheckBoxValueChanged(app, event)
            value = app.axisequalCheckBox.Value;
            
            if value
                app.plotto.DataAspectRatio = [ 1 1 1 ];
            else
                app.plotto.DataAspectRatioMode = 'auto';
            end
            
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            real_bool = app.realCheckBox.Value;
            pred_bool = app.predictionCheckBox.Value;
            esti_bool = app.estimationCheckBox.Value;
            
            n_state   = app.state_1.Value;
            n_state_2 = app.state_2.Value;
            
            cla(app.plotto)
            if app.statevstimeButton.Value
                hold(app.plotto,'on')
                if pred_bool
                    load("data_for_vis.mat",'xV');
                    plot(app.plotto,xV(n_state,:),'color',[ .2 .2 .8 ]);
                    hold(app.plotto,'on')
                end
                if esti_bool
                    load("data_for_vis.mat",'xPred');
                    plot(app.plotto,xPred(n_state,:),'color',[ .8 .2 .2 ]);
                    hold(app.plotto,'on')
                end
                if real_bool
                    load("data_for_vis.mat",'veh');
                    load("data_for_vis.mat",'rng_flt');
                    xT       = zeros(10,length(rng_flt));
                    xT(1,:)  = veh.v(rng_flt);
                    xT(2,:)  = veh.s(rng_flt);
                    xT(3,:)  = veh.n(rng_flt);
                    xT(4,:)  = veh.xi(rng_flt);
                    xT(5,:)  = veh.yawrate(rng_flt);
                    xT(6,:)  = veh.m0(1,ones(1,length(rng_flt)));
                    xT(7,:)  = veh.m0(2,ones(1,length(rng_flt)));
                    xT(8,:)  = veh.m0(3,ones(1,length(rng_flt)));
                    xT(9,:)  = veh.D(1,ones(1,length(rng_flt)));
                    xT(10,:) = veh.z(1,ones(1,length(rng_flt)));
                    plot(app.plotto,xT(n_state,:),'color',[ .2 .8 .2 ],'LineStyle',':',...
                        'LineWidth',3);
                    hold(app.plotto,'on')
                end
                hold(app.plotto,'off')
            elseif app.trajectoryButton.Value
                hold(app.plotto,'on')
                load("data_for_vis.mat",'*');
                dr.sdraw = 0:0.001:road.len;
                [ dr.xb0 , dr.yb0 , ~ ] = curvy_road.xytheta_by_s( dr.sdraw );
                [ dr.xbl , dr.ybl , ~ ] = curvy_road.xypsi_by_snxi( [ dr.sdraw ;  road.w*ones(1,length(dr.sdraw)) ; zeros(1,length(dr.sdraw)) ]);
                [ dr.xbr , dr.ybr , ~ ] = curvy_road.xypsi_by_snxi( [ dr.sdraw ; -road.w*ones(1,length(dr.sdraw)) ; zeros(1,length(dr.sdraw)) ]);
                plot(app.plotto,dr.xb0,dr.yb0,'black--',dr.xbl,dr.ybl,'blue-',dr.xbr,dr.ybr,'blue-');
                decimation = 20;
                [ final_traj.x , final_traj.y , final_traj.th ] = curvy_road.xypsi_by_snxi( xV(2:4,:) );
                plot(app.plotto,final_traj.x,final_traj.y,'red.','LineWidth',2)
                plot(app.plotto,veh.x(1:decimation:end),veh.y(1:decimation:end),'blue:','LineWidth',2);
                plot(app.plotto,magn.x,magn.y,'blackx');
                hold(app.plotto,'off')
            elseif app.headinganddipolesButton.Value  
                load("data_for_vis.mat",'*');
                value = app.sSlider.Value;
                index = 1 + floor( (rng_flt(end) - rng_flt(1)) * ( value/100 ) );
                [ final_traj.x , final_traj.y , final_traj.th ] = curvy_road.xypsi_by_snxi( xV(2:4,index) );
                plot(app.plotto,magn.x,magn.y,'blackx');
                if esti_bool
                    quiver(app.plotto,final_traj.x,final_traj.y,xV(6,index)./100,xV(7,index)./100,'color',[ .8 .2 .2 ]);
                end
                if real_bool
                    xT       = zeros(10,length(rng_flt));
                    xT(1,:)  = veh.v(rng_flt);
                    xT(2,:)  = veh.s(rng_flt);
                    xT(3,:)  = veh.n(rng_flt);
                    xT(4,:)  = veh.xi(rng_flt);
                    xT(5,:)  = veh.yawrate(rng_flt);
                    xT(6,:)  = veh.m0(1,ones(1,length(rng_flt)));
                    xT(7,:)  = veh.m0(2,ones(1,length(rng_flt)));
                    xT(8,:)  = veh.m0(3,ones(1,length(rng_flt)));
                    xT(9,:)  = veh.D(1,ones(1,length(rng_flt)));
                    xT(10,:) = veh.z(1,ones(1,length(rng_flt)));
                    quiver(app.plotto,final_traj.x,final_traj.y,xT(6,index)./100,xT(7,index)./100,'color',[ .2 .8 .2 ]);
                end
                axis(app.plotto,[ min(magn.x)-2 max(magn.x)+2 min(magn.y)-2 max(magn.y)+2])
            end
                
            
        end

        % Callback function
        function whichplotButtonGroupSelectionChanged(app, event)
            selectedButton = app.whichplotButtonGroup.SelectedObject;              
        end

        % Value changing function: sSlider
        function sSliderValueChanging(app, event)

        end

        % Value changed function: sSlider
        function sSliderValueChanged(app, event)

        end

        % Button pushed function: HoldoffButton
        function HoldoffButtonPushed(app, event)
            hold(app.plotto,'off');
        end

        % Button pushed function: HoldonButton
        function HoldonButtonPushed(app, event)
            hold(app.plotto,'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.state1Label = uilabel(app.UIFigure);
            app.state1Label.Text = 'state 1';
            app.state_1 = uispinner(app.UIFigure);
            app.state_1.Limits = [1 10];
            app.state_1.ValueChangedFcn = createCallbackFcn(app, @state_1ValueChanged, true);
            app.state_1.Value = 1;
            app.estimationCheckBox = uicheckbox(app.UIFigure);
            app.estimationCheckBox.Text = ' estimation';
            app.predictionCheckBox = uicheckbox(app.UIFigure);
            app.predictionCheckBox.Text = ' prediction';
            app.state2Label = uilabel(app.UIFigure);
            app.state2Label.Text = 'state 2';
            app.state_2 = uispinner(app.UIFigure);
            app.state_2.Limits = [1 10];
            app.state_2.Value = 1;
            app.realCheckBox = uicheckbox(app.UIFigure);
            app.realCheckBox.Text = ' real';
            app.plotto = uiaxes(app.UIFigure);
            title(app.plotto, 'Title')
            xlabel(app.plotto, 'X')
            ylabel(app.plotto, 'Y')
            app.axisequalCheckBox = uicheckbox(app.UIFigure);
            app.axisequalCheckBox.ValueChangedFcn = createCallbackFcn(app, @axisequalCheckBoxValueChanged, true);
            app.axisequalCheckBox.Text = 'axis equal';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.statevstimeButton = uiradiobutton(app.ButtonGroup);
            app.statevstimeButton.Text = 'state vs time';
            app.statevstimeButton.Value = true;
            app.statevsstateButton = uiradiobutton(app.ButtonGroup);
            app.statevsstateButton.Text = 'state vs state';
            app.trajectoryButton = uiradiobutton(app.ButtonGroup);
            app.trajectoryButton.Text = 'trajectory';
            app.headinganddipolesButton = uiradiobutton(app.ButtonGroup);
            app.headinganddipolesButton.Text = 'heading and dipoles';
            app.sSliderLabel = uilabel(app.UIFigure);
            app.sSliderLabel.Text = 's';
            app.sSlider = uislider(app.UIFigure);
            app.sSlider.ValueChangedFcn = createCallbackFcn(app, @sSliderValueChanged, true);
            app.sSlider.ValueChangingFcn = createCallbackFcn(app, @sSliderValueChanging, true);
            app.HoldoffButton = uibutton(app.UIFigure, 'push');
            app.HoldoffButton.ButtonPushedFcn = createCallbackFcn(app, @HoldoffButtonPushed, true);
            app.HoldoffButton.Text = 'Hold off!';
            app.HoldonButton = uibutton(app.UIFigure, 'push');
            app.HoldonButton.ButtonPushedFcn = createCallbackFcn(app, @HoldonButtonPushed, true);
            app.HoldonButton.Text = 'Hold on!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualize_state_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end